classdef GUI_normal_new < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        NormalTab                      matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout2_2                  matlab.ui.container.GridLayout
        InsideViewPanel                matlab.ui.container.Panel
        ResetCameraButton              matlab.ui.control.Button
        ReverseDirectionButton         matlab.ui.control.Button
        TurningAngleSlider             matlab.ui.control.Slider
        TurningAngleSliderLabel        matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        UploadCameraDataButton         matlab.ui.control.Button
        UploadPicturesButton_2         matlab.ui.control.Button
        MeasurementPanel               matlab.ui.container.Panel
        AreaEditField_2                matlab.ui.control.NumericEditField
        AreaEditField_2Label           matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        StartPlaneMeasurementButton_2  matlab.ui.control.Button
        StartLineMeasurementButton     matlab.ui.control.Button
        SelectlookingperspectivePanel  matlab.ui.container.Panel
        RightViewButton_2              matlab.ui.control.Button
        TopViewButton_2                matlab.ui.control.Button
        LeftViewButton_2               matlab.ui.control.Button
        FrontViewButton_2              matlab.ui.control.Button
        Create3DviewButton_2           matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        DebugTab                       matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout7                    matlab.ui.container.GridLayout
        UploadCameraDataButton_2       matlab.ui.control.Button
        UploadPicturesButton           matlab.ui.control.Button
        OutputPanel                    matlab.ui.container.Panel
        AreaEditField                  matlab.ui.control.NumericEditField
        AreaEditFieldLabel             matlab.ui.control.Label
        DistanceEditField              matlab.ui.control.NumericEditField
        DistanceEditFieldLabel         matlab.ui.control.Label
        MeasureDistancesPanel          matlab.ui.container.Panel
        StartPlaneMeasurementButton    matlab.ui.control.Button
        StartLineMeasurementButton_2   matlab.ui.control.Button
        Create3DviewButton_debug       matlab.ui.control.Button
        CameraManipulationPanel        matlab.ui.container.Panel
        RightViewButton                matlab.ui.control.Button
        TopViewButton                  matlab.ui.control.Button
        LeftViewButton                 matlab.ui.control.Button
        FrontViewButton                matlab.ui.control.Button
        ZSpinner                       matlab.ui.control.Spinner
        ZSpinnerLabel                  matlab.ui.control.Label
        YSpinner                       matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        XSpinner                       matlab.ui.control.Spinner
        XSpinnerLabel                  matlab.ui.control.Label
        UIAxes_debug                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Plot_3D_Data
        Plot_3D_Data_debug
        X_changingValue (1,1) double {mustBeReal(X_changingValue), mustBeNonNan(X_changingValue), mustBeInteger(X_changingValue), mustBeFinite(X_changingValue)} = 10;
        Y_changingValue (1,1) double {mustBeNonNan(Y_changingValue), mustBeFinite(Y_changingValue)} = 0;
        Z_changingValue (1,1) double {mustBeReal(Z_changingValue), mustBeNonNan(Z_changingValue), mustBeInteger(Z_changingValue), mustBeFinite(Z_changingValue)} = 10;
        cameradata = 0;
        cameradata2 = 0;
        filepath = 0;
        filepath2 = 0;
        points_3D = 0;
        points_3D_2 = 0;
        posxchangingValue double = 0;
        posychangingValue double = 0;
        poszchangingValue double = 0;
        tarxchangingValue double = 0;
        tarychangingValue double = 0;
        tarzchangingValue double = 0;
        turn double = 1;
        viewangle double = 2;
    end
    
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Create3DviewButton_debug
        function Create3DviewButton_debugPushed(comp, event)
            progressbar = waitbar(0,'Please wait...');
            waitbar(.25,progressbar,'Generating 3D-points from your pictures');
            
            %Colour Change after Selection
            comp.UploadPicturesButton_2.FontColor=[0,0,1];
            comp.UploadPicturesButton.FontColor=[0,0,1];
            comp.UploadCameraDataButton.FontColor=[0,0,1];
            comp.UploadCameraDataButton_2.FontColor=[0,0,1];

            figureHandle = ancestor(comp.UIAxes_debug, 'figure');

            %Check if Cameradata and Filpath are selected
            check = all([comp.cameradata2 comp.filepath2], 'all');

            if check
                %function for getting 3D points
                [~, comp.points_3D_2] = Main_get3dpoints(comp.filepath2, comp.cameradata2);

                waitbar(.50,progressbar,'Processing your data');
            
                comp.Plot_3D_Data_debug = script_3D_reconstruction(comp.UIAxes_debug, comp.points_3D_2);


            else
                uialert(figureHandle,'Filepath or Cameradata not selected','Error');
            end
            waitbar(.75,progressbar,'Plotting your data');
            plot_3D_all(comp.Plot_3D_Data_debug);
           

            waitbar(1,progressbar,'Finishing');
            close(progressbar)


%             zlim(comp.UIAxes, [-2 2])
%             ylim(comp.UIAxes, [-10 10])
%             xlim(comp.UIAxes, [-10 10])
            
            
        end

        % Button pushed function: UploadPicturesButton, 
        % ...and 1 other component
        function UploadPicturesfileexplorerButton_2Pushed(comp, event)
          
                   
            comp.filepath = 0;
            
            % Display message to select Folder
            msg = 'Select the folder containing the pictures.';
            uiwait(msgbox(msg, 'Select Points', 'modal'));
           
            try
                % Prompt the user to select a folder
                comp.filepath = uigetdir('Select a folder');
                % Display message for successful selection
                msg = 'Folder successfully selected.';
                uiwait(msgbox(msg, 'Select Points', 'modal'));
            catch
                warning('Please choose a valid folder and try again.');
                comp.cameradata =0;
            end
           
            comp.UploadPicturesButton.FontColor=[0,0.85,1];
            comp.UploadPicturesButton_2.FontColor=[0,0.85,1];
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(comp, event)
            comp.X_changingValue = event.Value;
            campos(comp.UIAxes_debug, [comp.X_changingValue, comp.Y_changingValue, comp.Z_changingValue]); % Set the camera position

        end

        % Value changing function: YSpinner
        function YSpinnerValueChanging(comp, event)
            comp.Y_changingValue = event.Value;
            campos(comp.UIAxes_debug, [comp.X_changingValue, comp.Y_changingValue, comp.Z_changingValue]); % Set the camera position
            
        end

        % Value changing function: ZSpinner
        function ZSpinnerValueChanging(comp, event)
            comp.Z_changingValue = event.Value;
            campos(comp.UIAxes_debug, [comp.X_changingValue, comp.Y_changingValue, comp.Z_changingValue]); % Set the camera positio
        end

        % Button pushed function: Create3DviewButton_2
        function Create3DviewButton_2Pushed(comp, event)
            progressbar = waitbar(0,'Please wait...');
            waitbar(.25,progressbar,'Generating 3D-points from your pictures');

            comp.UploadPicturesButton_2.FontColor=[0,0,1];
            comp.UploadPicturesButton.FontColor=[0,0,1];
            comp.UploadCameraDataButton.FontColor=[0,0,1];
            comp.UploadCameraDataButton_2.FontColor=[0,0,1];

            figureHandle = ancestor(comp.UIAxes, 'figure');
            check = all([comp.cameradata comp.filepath], 'all');

            if check
            [~, comp.points_3D] = Main_get3dpoints(comp.filepath, comp.cameradata );

            waitbar(.50,progressbar,'Processing your data');
            
         
            comp.Plot_3D_Data = script_3D_reconstruction(comp.UIAxes, comp.points_3D);


            else
            uialert(figureHandle,'Filepath or Cameradata not selected','Error');
            end
            waitbar(.75,progressbar,'Plotting your data');
            plot_3D_all(comp.Plot_3D_Data);
           

            waitbar(1,progressbar,'Finishing');
            close(progressbar)


%             zlim(comp.UIAxes, [-2 2])
%             ylim(comp.UIAxes, [-10 10])
%             xlim(comp.UIAxes, [-10 10])
        end

        % Button pushed function: FrontViewButton
        function FrontViewButtonPushed(comp, event)
         campos(comp.UIAxes_debug, [0, -100, 0]); % Set the camera position
        end

        % Button pushed function: TopViewButton
        function TopViewButtonPushed(comp, event)
         campos(comp.UIAxes_debug, [0, 0, 100]); % Set the camera position
        end

        % Button pushed function: LeftViewButton
        function LeftViewButtonPushed(comp, event)
       campos(comp.UIAxes_debug, [-100, 0, 0]); % Set the camera position
        end

        % Button pushed function: RightViewButton
        function RightViewButtonPushed(comp, event)
            campos(comp.UIAxes_debug, [100, 0, 0]); % Set the camera position  

        end

        % Button pushed function: StartLineMeasurementButton_2
        function StartLineMeasurementButton_2Pushed(comp, event)
            % roi = drawcuboid(comp.UIAxes);
%             % pos = roi.Position

            clc
            figureHandle = ancestor(comp.UIAxes_debug, 'figure');
            
            % Display message to select points
            msg = 'Select two points in the 3D plot.';
            uiwait(msgbox(msg, 'Select Points', 'modal'));

             % Initialize variables for storing selected points
            points = zeros(2, 3);
            counter = 1;
            
                        
            % Enable data cursor mode
            dcm_obj = datacursormode(figureHandle);
            set(dcm_obj, 'DisplayStyle', 'datatip', 'SnapToDataVertex', 'on', 'Enable', 'on');
             
             % remove existing data tips
            delete(findall(comp.UIAxes_debug, 'Type', 'datatip'));

            % Clear the current axes
            cla(comp.UIAxes_debug);
            plot_3D_all(comp.Plot_3D_Data_debug);
            
            % Set up a callback for data cursor update
            set(dcm_obj, 'UpdateFcn', @(src, event) dataCursorUpdate(src, event));   
           

            % Wait until two points are selected
            while counter <= 2
                drawnow;
                %disp("In Loop")
                

                %pause(1)
            end

            %disp("Points Selected")
             

            % Calculate the Euclidean distance between the two points
           if (norm(points(2, :) - points(1, :)))>0 && (norm(points(2, :) - points(1, :)))<100
               comp.DistanceEditField.Value = norm(points(2, :) - points(1, :));
               plot3(comp.UIAxes_debug, points(:, 1), points(:, 2), points(:, 3), 'ro-', 'MarkerFaceColor', 'r', 'LineWidth', 1.5, 'MarkerSize', 8, 'MarkerEdgeColor', 'k', 'LineStyle', '--');
           else
               uiwait(msgbox("Please Select valid points", 'Select Points', 'modal'));
           end


          % Callback function for data cursor update
            function txt = dataCursorUpdate(~, event)
                % Get the position of the selected point
                pos = event.Position;
                
                % Store the selected point
                points(counter, :) = pos;
                
                % Increment the counter
                counter = counter + 1;
                
                % Update the text to display the position of the selected point
                txt = {sprintf('X: %.2f', pos(1)), sprintf('Y: %.2f', pos(2)), sprintf('Z: %.2f', pos(3))};
            end
        end

        % Button pushed function: StartPlaneMeasurementButton
        function StartPlaneMeasurementButtonPushed(comp, event)
            clc
            figureHandle = ancestor(comp.UIAxes_debug, 'figure');
            
            % Display message to select points
            msg = 'Select four points in the 3D plot.';
            uiwait(msgbox(msg, 'Select Points', 'modal'));

             % Initialize variables for storing selected points
            points = zeros(4, 3);
            counter = 1;
            
                        
            % Enable data cursor mode
            dcm_obj = datacursormode(figureHandle);
            set(dcm_obj, 'DisplayStyle', 'datatip', 'SnapToDataVertex', 'on', 'Enable', 'on');
             
             % remove existing data tips
            delete(findall(comp.UIAxes_debug, 'Type', 'datatip'));
            % Clear the current axes
            cla(comp.UIAxes_debug);
            plot_3D_all(comp.Plot_3D_Data_debug);
            
            % Set up a callback for data cursor update
            set(dcm_obj, 'UpdateFcn', @(src, event) dataCursorUpdate(src, event));   
           

            % Wait until four points are selected
            while counter <= 4
                drawnow;
                %disp("In Loop")
                

                %pause(1)
            end

            %disp("Points Selected")
            v1 = points(2, :) - points(1, :);
            v2 = points(3, :) - points(1, :);
           
            % Define the four points of the plane
            P1 = points(1, :);
            P2 = points(2, :);
            P3 = points(3, :);
            P4 = points(4, :);
            
            if norm(cross(v1, v2))>0 && norm(cross(v1, v2))<100
            
                % Define the faces of the rectangle
                faces = [1, 2, 3, 4];
                % Create a matrix of the corner points of the rectangle
                vertices = [P1; P2; P3; P4];
    
                % Plot the semitransparent plane with patch
                patch('Vertices', vertices, 'Faces', faces, 'FaceAlpha', 0.5, 'FaceColor', 'b', 'EdgeColor', 'none', 'Parent', comp.UIAxes_debug);
                
                % Create a matrix of the corner points of the rectangle
                vertices = [P1; P3; P2; P4];
                
                % Plot the semitransparent plane with patch
                patch('Vertices', vertices, 'Faces', faces, 'FaceAlpha', 0.5, 'FaceColor', 'b', 'EdgeColor', 'none', 'Parent', comp.UIAxes_debug);
    
                % Create a matrix of the corner points of the rectangle
                vertices = [P1; P2; P4; P3];
                
                % Plot the semitransparent plane with patch
                patch('Vertices', vertices, 'Faces', faces, 'FaceAlpha', 0.5, 'FaceColor', 'b', 'EdgeColor', 'none', 'Parent', comp.UIAxes_debug);
               
                area = norm(cross(v1, v2));
                
                comp.AreaEditField.Value = area;
            else
                uiwait(msgbox("Please Select valid points", 'Select Points', 'modal'));
            end

          % Callback function for data cursor update
            function txt = dataCursorUpdate(~, event)
                % Get the position of the selected point
                pos = event.Position;
                
                % Store the selected point
                points(counter, :) = pos;
                
                % Increment the counter
                counter = counter + 1;
                
                % Update the text to display the position of the selected point
                txt = {sprintf('X: %.2f', pos(1)), sprintf('Y: %.2f', pos(2)), sprintf('Z: %.2f', pos(3))};
            end
        end

        % Button pushed function: FrontViewButton_2
        function FrontViewButton_2Pushed(comp, event)
         campos(comp.UIAxes, [0, -100, 0]); % Set the camera position
        end

        % Button pushed function: TopViewButton_2
        function TopViewButton_2Pushed(comp, event)
            campos(comp.UIAxes, [0, 0, 100]); % Set the camera position
        end

        % Button pushed function: LeftViewButton_2
        function LeftViewButton_2Pushed(comp, event)
            campos(comp.UIAxes, [-100, 0, 0]); % Set the camera position
        end

        % Button pushed function: RightViewButton_2
        function RightViewButton_2Pushed(comp, event)
            campos(comp.UIAxes, [100, 0, 0]); % Set the camera position 
        end

        % Button pushed function: StartLineMeasurementButton
        function StartLineMeasurementButtonPushed(comp, event)
            % roi = drawcuboid(comp.UIAxes);
%             % pos = roi.Position

            clc
            figureHandle = ancestor(comp.UIAxes, 'figure');
            % Display message to select points
            msg = 'Select two points in the 3D plot.';
            uiwait(msgbox(msg, 'Select Points', 'modal'));

             % Initialize variables for storing selected points
            points = zeros(2, 3);
            counter = 1;
            
                        
            % Enable data cursor mode
            dcm_obj = datacursormode(figureHandle);
            set(dcm_obj, 'DisplayStyle', 'datatip', 'SnapToDataVertex', 'on', 'Enable', 'on');
             
             % remove existing data tips
            delete(findall(comp.UIAxes, 'Type', 'datatip'));

            % Clear the current axes
            cla(comp.UIAxes);
            plot_3D_all(comp.Plot_3D_Data);
            
           % Set up a callback for data cursor update
            set(dcm_obj, 'UpdateFcn', @(src, event) dataCursorUpdate(src, event));   
           

            % Wait until two points are selected
            while counter <= 2
                drawnow;
                %disp("In Loop")                

                %pause(1)
            end

            %disp("Points Selected")
             

            % Calculate the Euclidean distance between the two points
            if norm(points(2, :) - points(1, :))>0 && norm(points(2, :) - points(1, :))<100
           comp.LengthEditField.Value = norm(points(2, :) - points(1, :));          
           plot3(comp.UIAxes, points(:, 1), points(:, 2), points(:, 3), 'ro-', 'MarkerFaceColor', 'r', 'LineWidth', 1.5, 'MarkerSize', 8, 'MarkerEdgeColor', 'k', 'LineStyle', '--');
            else
                uiwait(msgbox("Please Select valid points", 'Select Points', 'modal'));
                
            end
        
          % Callback function for data cursor update
            function txt = dataCursorUpdate(~, event)
                % Get the position of the selected point
                pos = event.Position;
                
                % Store the selected point
                points(counter, :) = pos;
                
                % Increment the counter
                counter = counter + 1;
                
                % Update the text to display the position of the selected point
                txt = {sprintf('X: %.2f', pos(1)), sprintf('Y: %.2f', pos(2)), sprintf('Z: %.2f', pos(3))};
            end
        end

        % Button pushed function: StartPlaneMeasurementButton_2
        function StartPlaneMeasurementButton_2Pushed(comp, event)
            clc
            figureHandle = ancestor(comp.UIAxes, 'figure');
            
            % Display message to select points
            msg = 'Select four points in the 3D plot.';
            uiwait(msgbox(msg, 'Select Points', 'modal'));

             % Initialize variables for storing selected points
            points = zeros(4, 3);
            counter = 1;
            
                        
            % Enable data cursor mode
            dcm_obj = datacursormode(figureHandle);
            set(dcm_obj, 'DisplayStyle', 'datatip', 'SnapToDataVertex', 'on', 'Enable', 'on');
             
             % remove existing data tips
            delete(findall(comp.UIAxes, 'Type', 'datatip'));
            % Clear the current axes
            cla(comp.UIAxes);
            plot_3D_all(comp.Plot_3D_Data);
            
            % Set up a callback for data cursor update
            set(dcm_obj, 'UpdateFcn', @(src, event) dataCursorUpdate(src, event));   
           

            % Wait until four points are selected
            while counter <= 4
                drawnow;
                %disp("In Loop")
                

                %pause(1)
            end

            %disp("Points Selected")
            v1 = points(2, :) - points(1, :);
            v2 = points(3, :) - points(1, :);
           
            % Define the four points of the plane
            P1 = points(1, :);
            P2 = points(2, :);
            P3 = points(3, :);
            P4 = points(4, :);
            
            
            if norm(cross(v1, v2)) > 0 && norm(cross(v1, v2))<100 && checkSamePlane(P1, P2, P3, P4)
                % Define the faces of the rectangle
                faces = [1, 2, 3, 4];
                % Create a matrix of the corner points of the rectangle
                vertices = [P1; P2; P3; P4];
    
                % Plot the semitransparent plane with patch
                patch('Vertices', vertices, 'Faces', faces, 'FaceAlpha', 0.5, 'FaceColor', 'b', 'EdgeColor', 'none', 'Parent', comp.UIAxes);
                
                % Create a matrix of the corner points of the rectangle
                vertices = [P1; P3; P2; P4];
                
                % Plot the semitransparent plane with patch
                patch('Vertices', vertices, 'Faces', faces, 'FaceAlpha', 0.5, 'FaceColor', 'b', 'EdgeColor', 'none', 'Parent', comp.UIAxes);
    
                % Create a matrix of the corner points of the rectangle
                vertices = [P1; P2; P4; P3];
                
                % Plot the semitransparent plane with patch
                patch('Vertices', vertices, 'Faces', faces, 'FaceAlpha', 0.5, 'FaceColor', 'b', 'EdgeColor', 'none', 'Parent', comp.UIAxes);
               
                area = norm(cross(v1, v2));
                
                comp.AreaEditField_2.Value = area;
            else
                uiwait(msgbox("Please Select valid points in one plane", 'Select Points', 'modal'));
            end
          % Callback function for data cursor update
            function txt = dataCursorUpdate(~, event)
                % Get the position of the selected point
                pos = event.Position;
                
                % Store the selected point
                points(counter, :) = pos;
                
                % Increment the counter
                counter = counter + 1;
                
                % Update the text to display the position of the selected point
                txt = {sprintf('X: %.2f', pos(1)), sprintf('Y: %.2f', pos(2)), sprintf('Z: %.2f', pos(3))};
            end

            function areInSamePlane = checkSamePlane(p1, p2, p3, p4)
            % Calculate vectors between the points
            v1 = p2 - p1;
            v2 = p3 - p1;
            v3 = p4 - p1;
        
            % Calculate the cross product of v1 and v2
            crossProduct = cross(v1, v2);
        
            % Check if v3 is parallel to the cross product vector
            areInSamePlane = abs(dot(crossProduct, v3)) < eps;
            end
        end

        % Button pushed function: UploadCameraDataButton
        function UploadCameraDataButtonPushed(comp, event)
            % Display message to select Data
            msg = 'Select Camera txt-file.';
            uiwait(msgbox(msg, 'Select Points', 'modal'));
            
            comp.cameradata = 0;
            try
                comp.cameradata = uigetfile('*.txt','MultiSelect','on');
                % Display message for successful selection
                msg = 'File successfully selected.';
                uiwait(msgbox(msg, 'Select Points', 'modal'));
            catch
                warning('Please choose a valid file and try again.');
                comp.cameradata =0;
            end
            comp.UploadCameraDataButton.FontColor=[0,0.85,1];
        end

        % Button pushed function: UploadCameraDataButton_2
        function UploadCameraDataButton_2Pushed(comp, event)
            
           % Display message to select Data
            msg = 'Select Camera txt-file.';
            uiwait(msgbox(msg, 'Select Points', 'modal'));
            
            comp.cameradata2 = 0;
            try
                comp.cameradata2 = uigetfile('*.txt','MultiSelect','on');
                % Display message for successful selection
                msg = 'File successfully selected.';
                uiwait(msgbox(msg, 'Select Points', 'modal'));
            catch
                warning('Please choose a valid file and try again.');
                comp.cameradata2 =0;
            end
            comp.UploadCameraDataButton_2.FontColor=[0,0.85,1];
        end

        % Callback function
        function posxSpinnerValueChanging(comp, event)
            comp.posxchangingValue = event.Value;
            disp([comp.posxchangingValue ,comp.posychangingValue, comp.poszchangingValue])
            set(comp.UIAxes, 'CameraPosition', [comp.posxchangingValue ,comp.posychangingValue, comp.poszchangingValue]);
        end

        % Callback function
        function posySpinnerValueChanging(comp, event)
              comp.posychangingValue = event.Value;
            set(comp.UIAxes, 'CameraPosition', [comp.posxchangingValue ,comp.posychangingValue, comp.poszchangingValue]);
        end

        % Callback function
        function poszSpinnerValueChanging(comp, event)
                        comp.poszchangingValue = event.Value;
            set(comp.UIAxes, 'CameraPosition', [comp.posxchangingValue, comp.posychangingValue ,comp.poszchangingValue]);
        end

        % Callback function
        function tarxSpinnerValueChanging(comp, event)
                        comp.tarxchangingValue = event.Value;
            set(comp.UIAxes, 'CameraTarget', [comp.tarxchangingValue, comp.tarychangingValue, comp.tarzchangingValue]);
        end

        % Callback function
        function tarySpinnerValueChanging(comp, event)
            comp.tarychangingValue = event.Value;
            set(comp.UIAxes, 'CameraTarget', [comp.tarxchangingValue, comp.tarychangingValue ,comp.tarzchangingValue]);
        end

        % Callback function
        function tarzSpinnerValueChanging(comp, event)
            comp.tarzchangingValue = event.Value;
            set(comp.UIAxes, 'CameraTarget', [comp.tarxchangingValue, comp.tarychangingValue ,comp.tarzchangingValue]);
            
        end

        % Callback function
        function viewangleSpinnerValueChanging(comp, event)
            comp.viewangle = event.Value;
            set(comp.UIAxes, 'CameraViewAngle', comp.viewangle);

            set(comp.UIAxes, 'CameraPosition', [comp.posxchangingValue ,comp.posychangingValue, comp.poszchangingValue]);
                        set(comp.UIAxes, 'CameraTarget', [comp.tarxchangingValue, comp.tarychangingValue ,comp.tarzchangingValue]);

        end

        % Value changing function: TurningAngleSlider
        function TurningAngleSliderValueChanging(comp, event)
            set(comp.UIAxes, 'CameraPosition', [0,0,-0.5]);
            changingValue = event.Value;
            set(comp.UIAxes, 'CameraViewAngle', 130);
            set(comp.UIAxes, 'CameraTarget', [-changingValue, comp.turn,0]);
            
        end

        % Button pushed function: ReverseDirectionButton
        function ReverseDirectionButtonPushed(comp, event)
            comp.turn=-comp.turn;
        end

        % Button pushed function: ResetCameraButton
        function ResetCameraButtonPushed(comp, event)
            set(comp.UIAxes, 'Projection', 'orthographic');
            set(comp.UIAxes, 'CameraPositionMode', 'auto');
             set(comp.UIAxes, 'CameraTargetMode', 'auto');
            set(comp.UIAxes, 'CameraUpVectorMode', 'auto');
            set(comp.UIAxes, 'CameraViewAngleMode', 'auto');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            %import test.*

            %disp(test(1,2));

        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 1144 799];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.Visible = true;
            comp.HandleVisibility = 'on';
            comp.GridLayout = uigridlayout(comp);
            comp.TabGroup = uitabgroup(comp.GridLayout);
            comp.NormalTab = uitab(comp.TabGroup);
            comp.NormalTab.Title = 'Normal';
            comp.GridLayout3 = uigridlayout(comp.NormalTab);
            comp.UIAxes = uiaxes(comp.GridLayout3);
            title(comp.UIAxes, 'Room View')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.GridLayout2_2 = uigridlayout(comp.GridLayout3);
            comp.Create3DviewButton_2 = uibutton(comp.GridLayout2_2, 'push');
            comp.Create3DviewButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Create3DviewButton_2Pushed, true);
            comp.Create3DviewButton_2.Text = 'Create 3D view';
            comp.SelectlookingperspectivePanel = uipanel(comp.GridLayout2_2);
            comp.SelectlookingperspectivePanel.Title = 'Select looking perspective';
            comp.FrontViewButton_2 = uibutton(comp.SelectlookingperspectivePanel, 'push');
            comp.FrontViewButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @FrontViewButton_2Pushed, true);
            comp.FrontViewButton_2.Text = 'Front View';
            comp.LeftViewButton_2 = uibutton(comp.SelectlookingperspectivePanel, 'push');
            comp.LeftViewButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LeftViewButton_2Pushed, true);
            comp.LeftViewButton_2.Text = 'Left View';
            comp.TopViewButton_2 = uibutton(comp.SelectlookingperspectivePanel, 'push');
            comp.TopViewButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @TopViewButton_2Pushed, true);
            comp.TopViewButton_2.Text = 'Top View';
            comp.RightViewButton_2 = uibutton(comp.SelectlookingperspectivePanel, 'push');
            comp.RightViewButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RightViewButton_2Pushed, true);
            comp.RightViewButton_2.Text = 'Right View';
            comp.MeasurementPanel = uipanel(comp.GridLayout2_2);
            comp.MeasurementPanel.Title = 'Measurement';
            comp.StartLineMeasurementButton = uibutton(comp.MeasurementPanel, 'push');
            comp.StartLineMeasurementButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StartLineMeasurementButtonPushed, true);
            comp.StartLineMeasurementButton.Text = 'Start Line Measurement';
            comp.StartPlaneMeasurementButton_2 = uibutton(comp.MeasurementPanel, 'push');
            comp.StartPlaneMeasurementButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StartPlaneMeasurementButton_2Pushed, true);
            comp.StartPlaneMeasurementButton_2.Text = 'Start Plane Measurement';
            comp.LengthEditFieldLabel = uilabel(comp.MeasurementPanel);
            comp.LengthEditFieldLabel.Text = 'Length';
            comp.LengthEditField = uieditfield(comp.MeasurementPanel, 'numeric');
            comp.AreaEditField_2Label = uilabel(comp.MeasurementPanel);
            comp.AreaEditField_2Label.Text = 'Area';
            comp.AreaEditField_2 = uieditfield(comp.MeasurementPanel, 'numeric');
            comp.GridLayout6 = uigridlayout(comp.GridLayout2_2);
            comp.UploadPicturesButton_2 = uibutton(comp.GridLayout6, 'push');
            comp.UploadPicturesButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UploadPicturesfileexplorerButton_2Pushed, true);
            comp.UploadPicturesButton_2.Icon = fullfile(pathToMLAPP, '126477.png');
            comp.UploadPicturesButton_2.Text = 'Upload Pictures';
            comp.UploadCameraDataButton = uibutton(comp.GridLayout6, 'push');
            comp.UploadCameraDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UploadCameraDataButtonPushed, true);
            comp.UploadCameraDataButton.Text = 'Upload Camera Data';
            comp.UploadCameraDataButton.Icon = fullfile(pathToMLAPP, 'camera.png');
            comp.InsideViewPanel = uipanel(comp.GridLayout2_2);
            comp.InsideViewPanel.Title = 'Inside View';
            comp.TurningAngleSliderLabel = uilabel(comp.InsideViewPanel);
            comp.TurningAngleSliderLabel.Text = 'Turning Angle';
            comp.TurningAngleSlider = uislider(comp.InsideViewPanel);
            comp.TurningAngleSlider.Limits = [-20 20];
            comp.TurningAngleSlider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            comp.TurningAngleSlider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @TurningAngleSliderValueChanging, true);
            comp.ReverseDirectionButton = uibutton(comp.InsideViewPanel, 'push');
            comp.ReverseDirectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ReverseDirectionButtonPushed, true);
            comp.ReverseDirectionButton.Text = 'Reverse Direction';
            comp.ResetCameraButton = uibutton(comp.InsideViewPanel, 'push');
            comp.ResetCameraButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ResetCameraButtonPushed, true);
            comp.ResetCameraButton.Text = 'Reset Camera';
            comp.DebugTab = uitab(comp.TabGroup);
            comp.DebugTab.Title = 'Debug';
            comp.GridLayout4 = uigridlayout(comp.DebugTab);
            comp.UIAxes_debug = uiaxes(comp.GridLayout4);
            title(comp.UIAxes_debug, 'Room View')
            xlabel(comp.UIAxes_debug, 'X')
            ylabel(comp.UIAxes_debug, 'Y')
            zlabel(comp.UIAxes_debug, 'Z')
            comp.GridLayout5 = uigridlayout(comp.GridLayout4);
            comp.CameraManipulationPanel = uipanel(comp.GridLayout5);
            comp.CameraManipulationPanel.Title = 'Camera Manipulation';
            comp.XSpinnerLabel = uilabel(comp.CameraManipulationPanel);
            comp.XSpinnerLabel.Text = 'X';
            comp.XSpinner = uispinner(comp.CameraManipulationPanel);
            comp.XSpinner.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @XSpinnerValueChanging, true);
            comp.YSpinnerLabel = uilabel(comp.CameraManipulationPanel);
            comp.YSpinnerLabel.Text = 'Y';
            comp.YSpinner = uispinner(comp.CameraManipulationPanel);
            comp.YSpinner.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @YSpinnerValueChanging, true);
            comp.ZSpinnerLabel = uilabel(comp.CameraManipulationPanel);
            comp.ZSpinnerLabel.Text = 'Z';
            comp.ZSpinner = uispinner(comp.CameraManipulationPanel);
            comp.ZSpinner.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @ZSpinnerValueChanging, true);
            comp.FrontViewButton = uibutton(comp.CameraManipulationPanel, 'push');
            comp.FrontViewButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @FrontViewButtonPushed, true);
            comp.FrontViewButton.Text = 'Front View';
            comp.LeftViewButton = uibutton(comp.CameraManipulationPanel, 'push');
            comp.LeftViewButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LeftViewButtonPushed, true);
            comp.LeftViewButton.Text = 'Left View';
            comp.TopViewButton = uibutton(comp.CameraManipulationPanel, 'push');
            comp.TopViewButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @TopViewButtonPushed, true);
            comp.TopViewButton.Text = 'Top View';
            comp.RightViewButton = uibutton(comp.CameraManipulationPanel, 'push');
            comp.RightViewButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RightViewButtonPushed, true);
            comp.RightViewButton.Text = 'Right View';
            comp.Create3DviewButton_debug = uibutton(comp.GridLayout5, 'push');
            comp.Create3DviewButton_debug.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Create3DviewButton_debugPushed, true);
            comp.Create3DviewButton_debug.Text = 'Create 3D view';
            comp.MeasureDistancesPanel = uipanel(comp.GridLayout5);
            comp.MeasureDistancesPanel.Title = 'Measure Distances';
            comp.StartLineMeasurementButton_2 = uibutton(comp.MeasureDistancesPanel, 'push');
            comp.StartLineMeasurementButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StartLineMeasurementButton_2Pushed, true);
            comp.StartLineMeasurementButton_2.Text = 'Start Line Measurement';
            comp.StartPlaneMeasurementButton = uibutton(comp.MeasureDistancesPanel, 'push');
            comp.StartPlaneMeasurementButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StartPlaneMeasurementButtonPushed, true);
            comp.StartPlaneMeasurementButton.Text = 'Start Plane Measurement';
            comp.OutputPanel = uipanel(comp.GridLayout5);
            comp.OutputPanel.Title = 'Output';
            comp.DistanceEditFieldLabel = uilabel(comp.OutputPanel);
            comp.DistanceEditFieldLabel.Text = 'Distance';
            comp.DistanceEditField = uieditfield(comp.OutputPanel, 'numeric');
            comp.AreaEditFieldLabel = uilabel(comp.OutputPanel);
            comp.AreaEditFieldLabel.Text = 'Area';
            comp.AreaEditField = uieditfield(comp.OutputPanel, 'numeric');
            comp.GridLayout7 = uigridlayout(comp.GridLayout5);
            comp.UploadPicturesButton = uibutton(comp.GridLayout7, 'push');
            comp.UploadPicturesButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UploadPicturesfileexplorerButton_2Pushed, true);
            comp.UploadPicturesButton.Icon = fullfile(pathToMLAPP, '126477.png');
            comp.UploadPicturesButton.Text = 'Upload Pictures';
            comp.UploadCameraDataButton_2 = uibutton(comp.GridLayout7, 'push');
            comp.UploadCameraDataButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UploadCameraDataButton_2Pushed, true);
            comp.UploadCameraDataButton_2.Text = 'Upload Camera Data';
            comp.UploadCameraDataButton_2.Icon = fullfile(pathToMLAPP, 'camera.png');
        end
    end
end